classdef robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ValuesPanel            matlab.ui.container.Panel
        ValuesLabel            matlab.ui.control.Label
        ValuesEditField        matlab.ui.control.EditField
        ProgramPanel           matlab.ui.container.Panel
        ProgramLabel           matlab.ui.control.Label
        ProgramDropDown        matlab.ui.control.DropDown
        GoButton               matlab.ui.control.Button
        TorquePanel            matlab.ui.container.Panel
        TorqueSwitch           matlab.ui.control.Switch
        TorqueSwitchLabel      matlab.ui.control.Label
        SetZeroLabel           matlab.ui.control.Label
        SetZeroLamp            matlab.ui.control.Lamp
        SetZeroButton          matlab.ui.control.Button
        StatusLamp             matlab.ui.control.Lamp
        DisconnectedLampLabel  matlab.ui.control.Label
        PortDropDown           matlab.ui.control.DropDown
        PortDropDownLabel      matlab.ui.control.Label
        ConnectButton          matlab.ui.control.Button
    end


    properties (Access = private)
        launcherInstance;
    end

    methods (Access = private)

        function addPrograms(app)
            % Get program names from the Launcher instance
            programNames = app.launcherInstance.programNames;

            % Filter out 'Plotting' program
            programNames = programNames(~strcmp(programNames, 'Plotting'));

            % Convert remaining program names to a cell array of character vectors
            dropdownItems = cellfun(@char, programNames, 'UniformOutput', false);

            % Set the filtered items in the ProgramDropDown dropdown
            app.ProgramDropDown.Items = dropdownItems;
        end

        function isPlottingProgram = checkIfCurrentProgramIsPlotting(app)
            % Initialize the return variable
            isPlottingProgram = false;

            % Check if there is a current program instance
            if ~isempty(app.launcherInstance.currentProgramInstance)
                % Check the 'name' property of the current program instance
                if strcmp(app.launcherInstance.currentProgramInstance.name, 'Plotting')
                    isPlottingProgram = true;
                end
            end
        end

        
        function closeProgramPanel(app)
            % Hide Control Panel
            app.ProgramPanel.Visible = 'off';
            app.GoButton.Visible = 'off';
            app.ValuesPanel.Visible = 'off';
            % Reset Values
            app.ValuesEditField.Value = '';
        end
        
        function openProgramPanel(app)
            app.ProgramPanel.Visible = "on";
            app.ProgramDropDownValueChanged;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            screenSize = get(0, 'ScreenSize'); % Get the screen size
            appWidth = screenSize(3) / 3; % App width is 1/3 of the screen width
            appHeight = screenSize(4); % App height is the full screen height

            % Set the position of the app
            app.UIFigure.Position = [screenSize(3) - appWidth, 1, appWidth, appHeight];

            % Set UIFigure to visible
            app.UIFigure.Visible = 'on';

            % Create the Launcher Instance
            app.launcherInstance = Launcher.getInstance();

            % Add properly defined programs to the list of programs
            % selectable in the dropdown. (It adds the name property).
            app.addPrograms;

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            selectedPort = app.PortDropDown.Value;

            if isempty(app.launcherInstance.realRobot)
                % Connect button
                try
                    % Try to Connect
                    app.launcherInstance.connect(selectedPort);
                    % Set Lamp to Connected
                    app.DisconnectedLampLabel.Text = 'Connected';
                    app.StatusLamp.Color = [0, 1, 0]; % Green color
                    app.ConnectButton.Text = 'Disconnect';
                    % Show Torque Panel
                    app.TorquePanel.Visible = 'on';
                    % Reset GUI
                    app.TorqueSwitch.Value = 'Off';
                    app.SetZeroLamp.Color = [1, 0, 0]; % Red color

                catch
                    % Set Lamp to Error
                    app.DisconnectedLampLabel.Text = 'Error';
                    app.StatusLamp.Color = [1, 0, 0]; % Red color
                    % Hide Torque Switch
                    app.TorquePanel.Visible = 'off';
                    app.closeProgramPanel;

                end
            else
                % Disconnect button
                app.launcherInstance.disconnect();
                app.DisconnectedLampLabel.Text = 'Disconnected';
                app.StatusLamp.Color = [1, 0, 0]; % Red color

                app.ConnectButton.Text = 'Connect';

                % Hide Torque Switch
                app.TorquePanel.Visible = 'off';
                app.closeProgramPanel;

                % Close all open figures
                close all;
            end
        end

        % Value changed function: TorqueSwitch
        function TorqueSwitchValueChanged(app, event)
            value = app.TorqueSwitch.Value;

            if strcmp(value, 'On')
                % If the switch is set to 'On', enable the robot's torque
                app.launcherInstance.realRobot.torqueEnable;
                % If the robots zero position is set then enable the
                % programs panel
                if isequal(app.SetZeroLamp.Color, [0, 1, 0])
                    app.openProgramPanel;
                end
            elseif strcmp(value, 'Off')
                % If the switch is set to 'Off', stop any running programm
                % except the plotting program
                if ~app.checkIfCurrentProgramIsPlotting
                    app.launcherInstance.stopCurrentProgram;
                end
                app.closeProgramPanel;

                app.launcherInstance.realRobot.torqueDisable;
            end
        end

        % Callback function: not associated with a component
        function XButtonPushed(app, event)

        end

        % Value changed function: ProgramDropDown
        function ProgramDropDownValueChanged(app, event)
            % Value changed function: ProgramDropDown
            selectedProgram = app.ProgramDropDown.Value;
            app.ValuesEditField.Value = '';
            app.GoButton.Visible = "on";

            % Handle different cases based on the selected program
            switch selectedProgram
                case 'Set_Joints'
                    % Code for handling 'Set_Joints'
                    % Set visibility, placeholders, etc.
                    % Example:
                    app.ValuesPanel.Visible = true;
                    app.ValuesLabel.Text = 'Joint Angles [deg]';
                    app.ValuesEditField.Placeholder = 'q1, q2, q3, q4';

                case 'Set_Position'
                    % Code for handling 'Set_Positions'
                    % Set visibility, placeholders, etc.
                    % Example:
                    app.ValuesPanel.Visible = true;
                    app.ValuesLabel.Text = 'TCP Position [mm]';
                    app.ValuesEditField.Placeholder = 'x, y, z';

                case 'Trajectory_2D'
                    app.ValuesPanel.Visible = false;
                    app.ValuesLabel.Text = '';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)

            % Disconnect
            app.launcherInstance.disconnect();

            % Close all open figures
            close all;


        end

        % Button pushed function: SetZeroButton
        function SetZeroButtonPushed(app, event)
            app.launcherInstance.realRobot.setZeroPositionToCurrentPosition;
            app.SetZeroLamp.Color = [0, 1, 0]; % Green color

            % If the torque is enabled, enable the programs panel
            if strcmp(app.TorqueSwitch.Value, 'On')
                app.openProgramPanel;
            end

        end

        % Callback function: not associated with a component
        function ProgramDropDownValueChanged2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port:';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8'};
            app.PortDropDown.Value = 'COM3';
            app.DisconnectedLampLabel = uilabel(app.UIFigure);
            app.DisconnectedLampLabel.Tag = 'Text';
            app.DisconnectedLampLabel.Text = 'Disconnected';
            app.StatusLamp = uilamp(app.UIFigure);
            app.TorquePanel = uipanel(app.UIFigure);
            app.TorquePanel.Visible = 'off';
            app.SetZeroButton = uibutton(app.TorquePanel, 'push');
            app.SetZeroButton.ButtonPushedFcn = createCallbackFcn(app, @SetZeroButtonPushed, true);
            app.SetZeroButton.Text = 'Set';
            app.SetZeroLamp = uilamp(app.TorquePanel);
            app.SetZeroLabel = uilabel(app.TorquePanel);
            app.SetZeroLabel.Text = 'Zero Position';
            app.TorqueSwitchLabel = uilabel(app.TorquePanel);
            app.TorqueSwitchLabel.Text = 'Torque';
            app.TorqueSwitch = uiswitch(app.TorquePanel, 'slider');
            app.TorqueSwitch.ValueChangedFcn = createCallbackFcn(app, @TorqueSwitchValueChanged, true);
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.Visible = 'off';
            app.GoButton.Text = 'Go';
            app.ProgramPanel = uipanel(app.UIFigure);
            app.ProgramPanel.Visible = 'off';
            app.ProgramDropDown = uidropdown(app.ProgramPanel);
            app.ProgramDropDown.ValueChangedFcn = createCallbackFcn(app, @ProgramDropDownValueChanged, true);
            app.ProgramLabel = uilabel(app.ProgramPanel);
            app.ProgramLabel.Text = 'Program';
            app.ValuesPanel = uipanel(app.UIFigure);
            app.ValuesPanel.Title = 'Panel';
            app.ValuesPanel.Visible = 'off';
            app.ValuesEditField = uieditfield(app.ValuesPanel, 'text');
            app.ValuesEditField.Placeholder = 'q1, q2, q3, q4';
            app.ValuesLabel = uilabel(app.ValuesPanel);
            app.ValuesLabel.Text = 'Value';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end